classdef BlackrockBatchLabelEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        RestartWizardMenu      matlab.ui.container.Menu
        StartConversionButton  matlab.ui.control.Button
        UITable                matlab.ui.control.Table
    end


    properties (Access = public)
        origFiles

        LabelLength = 16;
        ExtHeaderLength = 66;
        Table
        origLabels
        newLabels
        FIDPosition

        batchPosition = 1

    end

    methods (Access = private)

        function writeHeaderLabelsToTable(app,ElectrodeID,FIDPos,Label)
            app.Table = table(ElectrodeID,Label,FIDPos,Label);
            tmp = deblank(Label);
            app.Table(:,4) = tmp;

            app.UITable.Data = app.Table;
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable,s,'column',[1 3])
        end

        function [ElectrodeID,FIDPos,Label] = readHeaderLabels(app,file)
            FID = fopen(file, 'r', 'ieee-le');
            FileTypeID = fread(FID,[1,8],'uint8=>char');
            switch lower(FileTypeID)
                case 'neuralsg'
                    uialert(app.UIFigure,'Blackrock FileType is NEURALSG. NEURALSG files do not load extended channel header information.','Invalid FileType')
                case {'neuralcd','brsmpgrp'}
                    BasicHeader  = fread(FID, 306, '*uint8');
                    ChannelCount = double(typecast(BasicHeader(303:306), 'uint32'));

                    ElectrodeID = zeros(ChannelCount,1);
                    FIDPos      = zeros(ChannelCount,1);
                    Label       = cell(ChannelCount,1);

                    % Reading Extended Header Label Information
                    for headerIDX = 1:ChannelCount
                        Type = fread(FID,2,'uint8=>char')';
                        if (~strcmpi(Type, 'CC'))
                            uialert(app.UIFigure,'extended header not supported','Unsupported Header Type');
                            fclose(FID);
                            return;
                        end

                        tmp = fread(FID,2,'*uint8');
                        ElectrodeID(headerIDX) = typecast(tmp,'uint16');
                        FIDPos(headerIDX) = ftell(FID);
                        Label{headerIDX} = fread(FID,app.LabelLength,'uint8=>char')';
                        fseek(FID,app.ExtHeaderLength-20,'cof');
                    end
                otherwise
                    uialert(app.UIFigure,'This version can only read Blackrock File Specs 2.1, 2.2, 2.3, and 3.0.','Invalid FileType');
            end
        end

        function editFile(~,newFile,Labels,FIDPosition)
            FID = fopen(newFile,'r+','ieee-le');
            for i = 1:length(FIDPosition)
                fseek(FID,FIDPosition(i),'bof');
                fwrite(FID,Labels{i},'char','ieee-le');
            end
            fclose(FID);
        end

        function runBatchConversion(app)
            tmp = app.Table{:,2};
            app.origLabels = [app.origLabels,{tmp}];
            tmp = app.Table{:,4};
            tmp1 = cellfun(@(x) pad(x,app.LabelLength),tmp,'Uni',0);
            app.newLabels = [app.newLabels,{tmp1}];
            tmp = app.Table{:,3};
            app.FIDPosition = [app.FIDPosition,{tmp}];
            d = uiprogressdlg(app.UIFigure,'Value',(app.batchPosition(1)-1)/length(app.origFiles));
            d.Message = [int2str(app.batchPosition(1)-1),' of ',int2str(length(app.origFiles)),' Files Modified'];
            for i = app.batchPosition(1):length(app.origFiles)
                [path,filename,ext] = fileparts(app.origFiles{i});
                [EID,FIDP,L] = readHeaderLabels(app,app.origFiles{i});
                newFile = fullfile(path,[filename,'_EDIT',ext]);
                check = 1;
                for j = 1:length(app.origLabels)
                    C1 = setdiff(L,app.origLabels{j},'stable');
                    C2 = setdiff(FIDP,app.FIDPosition{j},'stable');
                    if isempty(C1) && isempty(C2)
                        copyfile(app.origFiles{i},newFile)
                        editFile(app,newFile,app.newLabels{j},app.FIDPosition{j})
                        check = 0;
                        break
                    end
                end
                if check
                    app.StartConversionButton.Text = 'Continue';
                    app.batchPosition = i;
                    close(d)
                    uiconfirm(app.UIFigure,'Labels of current file do not match any previously read files. Pausing batch processing so user can make new edited labels. Press ''Continue'' when ready to proceed.','New Labels','Options',{'OK'},'Icon','warning');
                    writeHeaderLabelsToTable(app,EID,FIDP,L)
                    return
                end
                d.Message = [int2str(i),' of ',int2str(length(app.origFiles)),' Files Modified'];
                d.Value = i/length(app.origFiles);
            end
            d.Message = 'Job Complete! Resetting app to defaults.';
            app.StartConversionButton.Text = 'Start Conversion';
            app.UITable.Data = [];
            resetProperties(app)
            pause(3)
            close(d)
        end

        function resetProperties(app)
            app.origFiles = [];

            app.Table = [];
            app.origLabels = [];
            app.newLabels = [];
            app.FIDPosition = [];

            app.batchPosition = 1;
        end

        function runWizard(app)
            ok = uiconfirm(app.UIFigure,'Welcome! The following application will assist in changing Blackrock NSx ElectrodesInfo labels at the bit level. Please be cautious when using this application.','Welome','Options',{'OK'},'Icon','');
            ok = uiconfirm(app.UIFigure,'We will start by selecting the files/folders you want to adjust.','Select Files','Options',{'OK'},'Icon','');
            pathData = uigetdir2();
            if isempty(pathData)
                ok = uiconfirm(app.UIFigure,'No files/folders selected. Wizard can be restarted from the ''File'' menu.','No Files/Folders Selected','Options',{'OK'},'Icon','')
                return
            end
            files = cell(length(pathData),1);
            for i = 1:length(pathData)
                tmp = dir(pathData{i});
                files{i} = tmp([tmp.isdir]==0);
            end
            files = cat(1,files{:});
            ind = cellfun(@isempty,regexp({files.name},'\.ns\d$'));
            if ~any(ind)
                ok = uiconfirm(app.UIFigure,'Blackrock NSx files not found. Wizard can be restarted from the ''File'' menu.','Files Not Found','Options',{'OK'},'Icon','')
            end
            tmp = files(~ind);
            tmpFiles = {tmp.name};
            tmpFiles = tmpFiles(:);
            tmpFolder = {tmp.folder};
            tmpFolder = tmpFolder(:);
            app.origFiles = cellfun(@(x,y) fullfile(x,y),tmpFolder,tmpFiles,'Uni',0);

            figure(app.UIFigure);
            %             ind = cellfun(@isempty,regexp(tmp,'\.ns\d$'));
            %             app.origFiles = tmp(~ind);
            %             ok = uiconfirm(app.UIFigure,'Now let''s select the path to save the new files. Note that this application does not allow the original and new paths to be the same as this would allow original files to be overwritten.','Select Saving Directory','Options',{'OK'},'Icon','');
            %             while 1
            %                 app.newPath = uigetdir('*.*');
            %                 app.UIFigure;
            %                 if isempty(app.newPath)
            %                     ok = uiconfirm(app.UIFigure,'New folder not selected. Wizard can be restarted from the ''File'' menu.','Folder not selected','Options',{'OK'},'Icon','')
            %                     return
            %                 elseif strcmp(app.origPath(1:end-1),app.newPath)
            %                     response = {'NO!!','NO! BAD USER!!','STOP THAT!!','STOP!'};
            %                     check = uiconfirm(app.UIFigure,[response{randi(length(response))},' Original and New paths cannot be the same! Would you like to select a new path?'],'Select New Path','Options',{'Yes','No'},'DefaultOption',1);
            %                     if strcmp(check,'Yes')
            %                         continue
            %                     else
            %                         ok = uiconfirm(app.UIFigure,'New folder not selected. Wizard can be restarted from the ''File'' menu.','Folder not selected','Options',{'OK'},'Icon','')
            %                         return
            %                     end
            %                 else
            %                     break
            %                 end
            %             end
            ok = uiconfirm(app.UIFigure,'Good to go! Modified files will be generated with ''_EDIT'' appended to the end of the filename. The following table shows the labels of the first listed Blackrock file. Make changes to the labels on the right-hand side and select ''Start Conversion'' when you''re ready to create modified label files.','Ready to Go','Options',{'OK'},'Icon','success');
            [EID,FIDP,L] = readHeaderLabels(app,app.origFiles{i});
            writeHeaderLabelsToTable(app,EID,FIDP,L)
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            runWizard(app)
        end

        % Menu selected function: RestartWizardMenu
        function RestartWizardMenuSelected(app, event)
            runWizard(app)
        end

        % Callback function
        function CreateEditedCopyMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Indeterminate','on');
            makeNewCopy(app)
            close(d)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            if length(event.NewData)>app.LabelLength
                uialert(app.UIFigure,'Labels cannot exceed 16 characters','Invalid Character Length')
                app.UITable.Data(indices(1),indices(2)) = cellstr(event.PreviousData);
                return
            end
            app.Table(indices(1),indices(2)) = cellstr(event.NewData);
            app.UITable.Data(indices(1),indices(2)) = cellstr(event.NewData);

        end

        % Button pushed function: StartConversionButton
        function StartConversionButtonPushed(app, event)
            runBatchConversion(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.RestartWizardMenu = uimenu(app.FileMenu);
            app.RestartWizardMenu.MenuSelectedFcn = createCallbackFcn(app, @RestartWizardMenuSelected, true);
            app.RestartWizardMenu.Text = 'Restart Wizard';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ElectrodeID'; 'Current Label'; 'FID Position'; 'New Label'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.StartConversionButton = uibutton(app.UIFigure, 'push');
            app.StartConversionButton.ButtonPushedFcn = createCallbackFcn(app, @StartConversionButtonPushed, true);
            app.StartConversionButton.Text = 'Start Conversion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlackrockBatchLabelEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end