classdef BlackrockHeaderEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        NewMenu               matlab.ui.container.Menu
        CreateEditedCopyMenu  matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = public)
        FileName
        FilePath
        FileExt
        LabelLength = 16;
        Table
        Test
    end
    
    methods (Access = private)
        
        function readHeader(app)
            ExtHeaderLength = 66;

            FID = fopen(fullfile(app.FilePath,[app.FileName,app.FileExt]), 'r', 'ieee-le');
            
            FileTypeID = fread(FID,[1,8],'uint8=>char');
            if strcmpi(FileTypeID, 'NEURALSG')
                uialert(app.UIFigure,'Blackrock FileType is NEURALSG. NEURALSG files do not load extended channel header information.','Invalid FileType')
                fclose(FID);
            elseif or(strcmpi(FileTypeID, 'NEURALCD'), strcmpi(FileTypeID, 'BRSMPGRP'))
                BasicHeader  = fread(FID, 306, '*uint8');
                ChannelCount = double(typecast(BasicHeader(303:306), 'uint32'));
                
                ElectrodeID = zeros(ChannelCount,1);
                Label       = cell(ChannelCount,1);
                FIDPosition = zeros(ChannelCount,1);
                
                
                %% Reading Extended Header Label Information
                for headerIDX = 1:ChannelCount
                    Type = fread(FID,2,'uint8=>char')';
                    if (~strcmpi(Type, 'CC'))
                        uialert(app.UIFigure,'extended header not supported','Unsupported Header Type');
                        fclose(FID);
                        return;
                    end
                    
                    tmp = fread(FID,2,'*uint8');
                    ElectrodeID(headerIDX) = typecast(tmp,'uint16');
                    FIDPosition(headerIDX) = ftell(FID);
                    Label{headerIDX} = fread(FID,16,'uint8=>char')';
                    fseek(FID,ExtHeaderLength-20,'cof');
                    
                end
                
                app.Table = table(ElectrodeID,Label,FIDPosition,Label);
                tmp = deblank(Label);
                app.Table(:,4) = tmp;
                
                app.UITable.Data = app.Table;
                s = uistyle('HorizontalAlignment','center');
                addStyle(app.UITable,s,'column',[1 3])
                

            else
                uialert(app.UIFigure,'This version can only read Blackrock File Specs 2.1, 2.2, 2.3, and 3.0.','Invalid FileType');
                fclose(FID);
                return;
            end
            fclose(FID);
            
        end
        
        
        function makeNewCopy(app)
            origFile = fullfile(app.FilePath,[app.FileName,app.FileExt]);
            newFile = fullfile(app.FilePath,[app.FileName,'_EDIT',app.FileExt]);
            copyfile(origFile,newFile)
            
            tmp = app.Table{:,4};
            NewLabels = cellfun(@(x) pad(x,app.LabelLength),tmp,'Uni',0);
            FIDPosition = app.Table{:,3};
            
            FID = fopen(newFile,'r+','ieee-le');
            for i = 1:length(FIDPosition)
                fseek(FID,FIDPosition(i),'bof');
                fwrite(FID,NewLabels{i},'char','ieee-le');
            end
            fclose(FID);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [file,path] = uigetfile('*.*');
            if isempty(file)
                return
            end
            [app.FilePath,app.FileName,app.FileExt] = fileparts(fullfile(path,file));
            readHeader(app)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            startupFcn(app)
        end

        % Menu selected function: CreateEditedCopyMenu
        function CreateEditedCopyMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Indeterminate','on');
            makeNewCopy(app)
            close(d)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            if length(event.NewData)>app.LabelLength
                uialert(app.UIFigure,'Labels cannot exceed 16 characters','Invalid Character Length')
                app.UITable.Data(indices(1),indices(2)) = cellstr(event.PreviousData);
                return
            end
            app.Table(indices(1),indices(2)) = cellstr(event.NewData);
            app.UITable.Data(indices(1),indices(2)) = cellstr(event.NewData);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.CreateEditedCopyMenu = uimenu(app.FileMenu);
            app.CreateEditedCopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateEditedCopyMenuSelected, true);
            app.CreateEditedCopyMenu.Text = 'Create Edited Copy';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'ElectrodeID'; 'Current Label'; 'FID Position'; 'New Label'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlackrockHeaderEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end